classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ControlTab                      matlab.ui.container.Tab
        OperationModeButtonGroup        matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.RadioButton
        AutomaticButton                 matlab.ui.control.RadioButton
        PDControllerGainsPanel          matlab.ui.container.Panel
        RollControlLabel                matlab.ui.control.Label
        PitchControlLabel               matlab.ui.control.Label
        ProportionalGainEditFieldLabel  matlab.ui.control.Label
        ProportionalGainEditFieldRoll   matlab.ui.control.NumericEditField
        TimeConstantEditFieldLabel      matlab.ui.control.Label
        TimeConstantEditFieldRoll       matlab.ui.control.NumericEditField
        DerivativeGainEditFieldLabel    matlab.ui.control.Label
        DerivativeGainEditFieldRoll     matlab.ui.control.NumericEditField
        AltitudeControlLabel            matlab.ui.control.Label
        TimeConstantEditField_2Label    matlab.ui.control.Label
        TimeConstantEditFieldPitch      matlab.ui.control.NumericEditField
        DerivativeGainEditField_2Label  matlab.ui.control.Label
        DerivativeGainEditFieldPitch    matlab.ui.control.NumericEditField
        ProportionalGainEditField_2Label  matlab.ui.control.Label
        ProportionalGainEditFieldPitch  matlab.ui.control.NumericEditField
        TimeConstantEditField_3Label    matlab.ui.control.Label
        TimeConstantEditFieldAltitude   matlab.ui.control.NumericEditField
        DerivativeGainEditField_3Label  matlab.ui.control.Label
        DerivativeGainEditFieldAltitude  matlab.ui.control.NumericEditField
        ProportionalGainEditField_3Label  matlab.ui.control.Label
        ProportionalGainEditFieldAltitude  matlab.ui.control.NumericEditField
        MotorControlPanel               matlab.ui.container.Panel
        AllSliderLabel                  matlab.ui.control.Label
        AllSlider                       matlab.ui.control.Slider
        Motor3SliderLabel               matlab.ui.control.Label
        Motor3Slider                    matlab.ui.control.Slider
        Motor1SliderLabel               matlab.ui.control.Label
        Motor1Slider                    matlab.ui.control.Slider
        Motor2SliderLabel               matlab.ui.control.Label
        Motor2Slider                    matlab.ui.control.Slider
        MotorConfigurationPanel         matlab.ui.container.Panel
        MaxMotorVoltageEditFieldLabel   matlab.ui.control.Label
        MaxMotorVoltageEditField        matlab.ui.control.NumericEditField
        InitialCenterofMassPositionPanel  matlab.ui.container.Panel
        CoMPositionEditFieldLabel       matlab.ui.control.Label
        CoMPositionEditFieldX           matlab.ui.control.NumericEditField
        CoMPositionEditFieldY           matlab.ui.control.NumericEditField
        CoMPositionEditFieldZ           matlab.ui.control.NumericEditField
        CameraControlTab                matlab.ui.container.Tab
        CameraSelectionButtonGroup      matlab.ui.container.ButtonGroup
        BottomCameraAButton             matlab.ui.control.RadioButton
        BottomCameraBButton             matlab.ui.control.RadioButton
        SideCameraPanel                 matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldSideRow  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldSideCol  matlab.ui.control.NumericEditField
        HoldReleaseButtonSide           matlab.ui.control.Button
        ROISizeEditFieldLabel           matlab.ui.control.Label
        ROISizeEditField                matlab.ui.control.NumericEditField
        ROISizeEditField_2              matlab.ui.control.NumericEditField
        ImageDimensionsEditFieldLabel   matlab.ui.control.Label
        ImageDimensionsEditField        matlab.ui.control.NumericEditField
        ROISizeEditField_4              matlab.ui.control.NumericEditField
        CrosshairWidthEditFieldLabel    matlab.ui.control.Label
        CrosshairWidthEditField         matlab.ui.control.NumericEditField
        VideoDecimationEditFieldLabel   matlab.ui.control.Label
        VideoDecimationEditField        matlab.ui.control.NumericEditField
        ImageWidthEditFieldLabel        matlab.ui.control.Label
        ImageWidthEditField             matlab.ui.control.NumericEditField
        SideCameraLabel_2               matlab.ui.control.Label
        SideCameraPanel_2               matlab.ui.container.Panel
        ROIHoldPositionpxEditFieldLabel_2  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldSideRow_2  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldSideCol_2  matlab.ui.control.NumericEditField
        HoldReleaseButtonSide_2         matlab.ui.control.Button
        ROISizeEditField_5Label         matlab.ui.control.Label
        ROISizeEditField_5              matlab.ui.control.NumericEditField
        ROISizeEditField_6              matlab.ui.control.NumericEditField
        ImageDimensionsEditField_2Label  matlab.ui.control.Label
        ImageDimensionsEditField_2      matlab.ui.control.NumericEditField
        ROISizeEditField_7              matlab.ui.control.NumericEditField
        CrosshairWidthEditField_2Label  matlab.ui.control.Label
        CrosshairWidthEditField_2       matlab.ui.control.NumericEditField
        VideoDecimationEditField_2Label  matlab.ui.control.Label
        VideoDecimationEditField_2      matlab.ui.control.NumericEditField
        ImageWidthEditField_2Label      matlab.ui.control.Label
        ImageWidthEditField_2           matlab.ui.control.NumericEditField
        SideCameraPanel_3               matlab.ui.container.Panel
        SideCameraLabel_3               matlab.ui.control.Label
        ROIHoldPositionpxEditFieldLabel_3  matlab.ui.control.Label
        ROIHoldPositionpxEditFieldSideRow_3  matlab.ui.control.NumericEditField
        ROIHoldPositionpxEditFieldSideCol_3  matlab.ui.control.NumericEditField
        HoldReleaseButtonSide_3         matlab.ui.control.Button
        ROISizeEditField_8Label         matlab.ui.control.Label
        ROISizeEditField_8              matlab.ui.control.NumericEditField
        ROISizeEditField_9              matlab.ui.control.NumericEditField
        ImageDimensionsEditField_3Label  matlab.ui.control.Label
        ImageDimensionsEditField_3      matlab.ui.control.NumericEditField
        ROISizeEditField_10             matlab.ui.control.NumericEditField
        CrosshairWidthEditField_3Label  matlab.ui.control.Label
        CrosshairWidthEditField_3       matlab.ui.control.NumericEditField
        VideoDecimationEditField_3Label  matlab.ui.control.Label
        VideoDecimationEditField_3      matlab.ui.control.NumericEditField
        ImageWidthEditField_3Label      matlab.ui.control.Label
        ImageWidthEditField_3           matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldLabel_2  matlab.ui.control.Label
        BottomADotPositionVectorEditFieldX  matlab.ui.control.NumericEditField
        CameraSettingsTab               matlab.ui.container.Tab
        CameraOrientationPanel          matlab.ui.container.Panel
        SideCameraLabel2                matlab.ui.control.Label
        EulerAngleEditFieldLabel_2      matlab.ui.control.Label
        EulerAngleEditFieldSideRoll     matlab.ui.control.NumericEditField
        BottomCameraLabel               matlab.ui.control.Label
        EulerAngleEditField_2Label_2    matlab.ui.control.Label
        EulerAngleEditFieldBottomRoll   matlab.ui.control.NumericEditField
        EulerAngleEditFieldBottomPitch  matlab.ui.control.NumericEditField
        EulerAngleEditFieldBottomYaw    matlab.ui.control.NumericEditField
        PositionEditField_8Label_2      matlab.ui.control.Label
        PositionEditFieldBottomX        matlab.ui.control.NumericEditField
        PositionEditFieldBottomY        matlab.ui.control.NumericEditField
        PositionEditFieldBottomZ        matlab.ui.control.NumericEditField
        SlantCameraLabel2               matlab.ui.control.Label
        EulerAngleEditField_3Label_2    matlab.ui.control.Label
        EulerAngleEditFieldSlantRoll    matlab.ui.control.NumericEditField
        PositionEditField_11Label_2     matlab.ui.control.Label
        PositionEditFieldSlantX         matlab.ui.control.NumericEditField
        PositionEditFieldSlantY         matlab.ui.control.NumericEditField
        EulerAngleEditFieldSlantPitch   matlab.ui.control.NumericEditField
        EulerAngleEditFieldSlantYaw     matlab.ui.control.NumericEditField
        PositionEditFieldSlantZ         matlab.ui.control.NumericEditField
        PositionEditField_16Label_2     matlab.ui.control.Label
        PositionEditFieldSideX          matlab.ui.control.NumericEditField
        EulerAngleEditFieldSidePitch    matlab.ui.control.NumericEditField
        EulerAngleEditFieldSideYaw      matlab.ui.control.NumericEditField
        PositionEditFieldSideY          matlab.ui.control.NumericEditField
        PositionEditFieldSideZ          matlab.ui.control.NumericEditField
        ImageErrorSettingsPanel         matlab.ui.container.Panel
        ThresholdEditField_2Label       matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        DebounceTimeEditField_2Label    matlab.ui.control.Label
        DebounceTimeEditField           matlab.ui.control.NumericEditField
        CameraSpecificationsPanel       matlab.ui.container.Panel
        CameraFOVEditFieldLabel_2       matlab.ui.control.Label
        CameraFOVEditFieldHor           matlab.ui.control.NumericEditField
        CameraFOVEditFieldVer           matlab.ui.control.NumericEditField
        DotPropertiesPanel              matlab.ui.container.Panel
        SideDotSeparationDistanceEditField_2Label  matlab.ui.control.Label
        SideDotSeparationDistanceEditField  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldLabel_2  matlab.ui.control.Label
        SideDotPositionVectorEditFieldX  matlab.ui.control.NumericEditField
        BottomADotSeparationDistanceEditField_2Label  matlab.ui.control.Label
        BottomADotSeparationDistanceEditField  matlab.ui.control.NumericEditField
        BottomBDotSeparationDistanceEditField_2Label  matlab.ui.control.Label
        BottomBDotSeparationDistanceEditField  matlab.ui.control.NumericEditField
        BottomBDotPositionValuieEditFieldLabel_2  matlab.ui.control.Label
        BottomBDotPositionValuieEditFieldX  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        SideDotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldY  matlab.ui.control.NumericEditField
        BottomADotPositionVectorEditFieldZ  matlab.ui.control.NumericEditField
        BottomBDotPositionValuieEditFieldY  matlab.ui.control.NumericEditField
        BottomBDotPositionValuieEditFieldZ  matlab.ui.control.NumericEditField
        CommunicationTab                matlab.ui.container.Tab
        UDPPTPConfigurationPanel        matlab.ui.container.Panel
        PTPOffsetEditFieldLabel         matlab.ui.control.Label
        PTPOffsetEditField              matlab.ui.control.NumericEditField
        SyncIntervalEditFieldLabel      matlab.ui.control.Label
        SyncIntervalEditField           matlab.ui.control.NumericEditField
        Machine2Panel                   matlab.ui.container.Panel
        IPAddressEditFieldLabel         matlab.ui.control.Label
        IPAddressEditFieldMachine2      matlab.ui.control.NumericEditField
        PortNumberEditFieldLabel        matlab.ui.control.Label
        PortNumberEditFieldMachine2     matlab.ui.control.NumericEditField
        MinDelayEditFieldLabel          matlab.ui.control.Label
        MinDelayEditFieldMachine2       matlab.ui.control.NumericEditField
        Machine3Panel                   matlab.ui.container.Panel
        IPAddressEditField_2Label       matlab.ui.control.Label
        IPAddressEditFieldMachine3      matlab.ui.control.NumericEditField
        PortNumberEditField_2Label      matlab.ui.control.Label
        PortNumberEditFieldMachine3     matlab.ui.control.NumericEditField
        MinDelayEditField_2Label        matlab.ui.control.Label
        MinDelayEditFieldMachine3       matlab.ui.control.NumericEditField
        Machine4Panel                   matlab.ui.container.Panel
        IPAddressEditField_3Label       matlab.ui.control.Label
        IPAddressEditFieldMachine4      matlab.ui.control.NumericEditField
        PortNumberEditField_3Label      matlab.ui.control.Label
        PortNumberEditFieldMachine4     matlab.ui.control.NumericEditField
        MinDelayEditField_3Label        matlab.ui.control.Label
        MinDelayEditFieldMachine4       matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Value changed function: ProportionalGainEditFieldRoll
        function ProportionalGainEditFieldRollValueChanged(app, event)
            value = app.ProportionalGainEditFieldRoll.Value;
            assignin('base','params.rollCtrlkp_Prad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditFieldRoll
        function DerivativeGainEditFieldRollValueChanged(app, event)
            value = app.DerivativeGainEditFieldRoll.Value;
            assignin('base','params.rollCtrlkd_sPrad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditFieldRoll
        function TimeConstantEditFieldRollValueChanged(app, event)
            value = app.TimeConstantEditFieldRoll.Value;
            assignin('base','params.rollCtrltau_s',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: ProportionalGainEditFieldPitch
        function ProportionalGainEditFieldPitchValueChanged(app, event)
            value = app.ProportionalGainEditFieldPitch.Value;
            assignin('base','params.pitchCtrlkp_Prad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditFieldPitch
        function DerivativeGainEditFieldPitchValueChanged(app, event)
            value = app.DerivativeGainEditFieldPitch.Value;
            assignin('base','params.pitchCtrlkd_sPrad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditFieldPitch
        function TimeConstantEditFieldPitchValueChanged(app, event)
            value = app.TimeConstantEditFieldPitch.Value;
            assignin('base','params.pitchCtrltau_s',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: ProportionalGainEditFieldAltitude
        function ProportionalGainEditFieldAltitudeValueChanged(app, event)
            value = app.ProportionalGainEditFieldAltitude.Value;
            assignin('base','params.altCtrlkp_Prad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: DerivativeGainEditFieldAltitude
        function DerivativeGainEditFieldAltitudeValueChanged(app, event)
            value = app.DerivativeGainEditFieldAltitude.Value;
            assignin('base','params.altCtrlkd_sPrad',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TimeConstantEditFieldAltitude
        function TimeConstantEditFieldAltitudeValueChanged(app, event)
            value = app.TimeConstantEditFieldAltitude.Value;
            assignin('base','params.altCtrltau_s',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor1Slider
        function Motor1SliderValueChanged(app, event)
            value = app.Motor1Slider.Value;
            assignin('base','params.mtr1ManulaSpdCmd_na',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor2Slider
        function Motor2SliderValueChanged(app, event)
            value = app.Motor2Slider.Value;
            assignin('base','params.mtr2ManulaSpdCmd_na',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: Motor3Slider
        function Motor3SliderValueChanged(app, event)
            value = app.Motor3Slider.Value;
            assignin('base','params.mtr3ManulaSpdCmd_na',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: MaxMotorVoltageEditField
        function MaxMotorVoltageEditFieldValueChanged(app, event)
            value = app.MaxMotorVoltageEditField.Value;
            assignin('base','params.maxOutVltg_vlt',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: CoMPositionEditFieldX
        function CoMPositionEditFieldXValueChanged(app, event)
            value = app.CoMPositionEditFieldX.Value;
            assignin('base','params.initCoMPosVec_cm',value);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: AllSlider
        function AllSliderValueChanged(app, event)
            value = app.AllSlider.Value;
            assignin('base','params.mtr1ManulaSpdCmd_na',value);
            assignin('base','params.mtr2ManulaSpdCmd_na',value);
            assignin('base','params.mtr3ManulaSpdCmd_na',value);
            set_param(gcs,'SimulationCommand','Update');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.OperationModeButtonGroup = uibuttongroup(app.ControlTab);
            app.OperationModeButtonGroup.Title = 'Operation Mode';
            app.ManualButton = uiradiobutton(app.OperationModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutomaticButton = uiradiobutton(app.OperationModeButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.PDControllerGainsPanel = uipanel(app.ControlTab);
            app.PDControllerGainsPanel.Title = 'PD Controller Gains';
            app.RollControlLabel = uilabel(app.PDControllerGainsPanel);
            app.RollControlLabel.Text = 'Roll Control';
            app.PitchControlLabel = uilabel(app.PDControllerGainsPanel);
            app.PitchControlLabel.Text = 'Pitch Control';
            app.ProportionalGainEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditFieldLabel.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldRollValueChanged, true);
            app.TimeConstantEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditFieldLabel.Text = 'Time Constant';
            app.TimeConstantEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldRollValueChanged, true);
            app.DerivativeGainEditFieldLabel = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditFieldLabel.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldRoll = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldRoll.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldRollValueChanged, true);
            app.AltitudeControlLabel = uilabel(app.PDControllerGainsPanel);
            app.AltitudeControlLabel.Text = 'Altitude Control';
            app.TimeConstantEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditField_2Label.Text = 'Time Constant';
            app.TimeConstantEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldPitchValueChanged, true);
            app.DerivativeGainEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditField_2Label.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldPitchValueChanged, true);
            app.ProportionalGainEditField_2Label = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditField_2Label.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldPitch = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldPitch.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldPitchValueChanged, true);
            app.TimeConstantEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.TimeConstantEditField_3Label.Text = 'Time Constant';
            app.TimeConstantEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.TimeConstantEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @TimeConstantEditFieldAltitudeValueChanged, true);
            app.DerivativeGainEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.DerivativeGainEditField_3Label.Text = 'Derivative Gain';
            app.DerivativeGainEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.DerivativeGainEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldAltitudeValueChanged, true);
            app.ProportionalGainEditField_3Label = uilabel(app.PDControllerGainsPanel);
            app.ProportionalGainEditField_3Label.Text = 'Proportional Gain';
            app.ProportionalGainEditFieldAltitude = uieditfield(app.PDControllerGainsPanel, 'numeric');
            app.ProportionalGainEditFieldAltitude.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldAltitudeValueChanged, true);
            app.MotorControlPanel = uipanel(app.ControlTab);
            app.MotorControlPanel.Title = 'Motor Control';
            app.AllSliderLabel = uilabel(app.MotorControlPanel);
            app.AllSliderLabel.Text = 'All';
            app.AllSlider = uislider(app.MotorControlPanel);
            app.AllSlider.Limits = [-1 1];
            app.AllSlider.ValueChangedFcn = createCallbackFcn(app, @AllSliderValueChanged, true);
            app.Motor3SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor3SliderLabel.Text = 'Motor 3';
            app.Motor3Slider = uislider(app.MotorControlPanel);
            app.Motor3Slider.Limits = [-1 1];
            app.Motor3Slider.ValueChangedFcn = createCallbackFcn(app, @Motor3SliderValueChanged, true);
            app.Motor1SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor1SliderLabel.Text = 'Motor 1';
            app.Motor1Slider = uislider(app.MotorControlPanel);
            app.Motor1Slider.Limits = [-1 1];
            app.Motor1Slider.ValueChangedFcn = createCallbackFcn(app, @Motor1SliderValueChanged, true);
            app.Motor2SliderLabel = uilabel(app.MotorControlPanel);
            app.Motor2SliderLabel.Text = 'Motor 2';
            app.Motor2Slider = uislider(app.MotorControlPanel);
            app.Motor2Slider.Limits = [-1 1];
            app.Motor2Slider.ValueChangedFcn = createCallbackFcn(app, @Motor2SliderValueChanged, true);
            app.MotorConfigurationPanel = uipanel(app.ControlTab);
            app.MotorConfigurationPanel.Title = 'Motor Configuration';
            app.MaxMotorVoltageEditFieldLabel = uilabel(app.MotorConfigurationPanel);
            app.MaxMotorVoltageEditFieldLabel.Text = 'Max Motor Voltage';
            app.MaxMotorVoltageEditField = uieditfield(app.MotorConfigurationPanel, 'numeric');
            app.MaxMotorVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @MaxMotorVoltageEditFieldValueChanged, true);
            app.InitialCenterofMassPositionPanel = uipanel(app.ControlTab);
            app.InitialCenterofMassPositionPanel.Title = 'Initial Center of Mass Position';
            app.CoMPositionEditFieldLabel = uilabel(app.InitialCenterofMassPositionPanel);
            app.CoMPositionEditFieldLabel.Text = 'CoM Position';
            app.CoMPositionEditFieldX = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldX.ValueChangedFcn = createCallbackFcn(app, @CoMPositionEditFieldXValueChanged, true);
            app.CoMPositionEditFieldY = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CoMPositionEditFieldZ = uieditfield(app.InitialCenterofMassPositionPanel, 'numeric');
            app.CameraControlTab = uitab(app.TabGroup);
            app.CameraControlTab.Title = 'Camera Control';
            app.CameraSelectionButtonGroup = uibuttongroup(app.CameraControlTab);
            app.CameraSelectionButtonGroup.Title = 'Camera Selection';
            app.BottomCameraAButton = uiradiobutton(app.CameraSelectionButtonGroup);
            app.BottomCameraAButton.Text = 'Bottom Camera A';
            app.BottomCameraAButton.Value = true;
            app.BottomCameraBButton = uiradiobutton(app.CameraSelectionButtonGroup);
            app.BottomCameraBButton.Text = 'Bottom Camera B';
            app.SideCameraPanel = uipanel(app.CameraControlTab);
            app.SideCameraPanel.Title = 'Side Camera';
            app.ROIHoldPositionpxEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ROIHoldPositionpxEditFieldLabel.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldSideRow = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROIHoldPositionpxEditFieldSideCol = uieditfield(app.SideCameraPanel, 'numeric');
            app.HoldReleaseButtonSide = uibutton(app.SideCameraPanel, 'push');
            app.HoldReleaseButtonSide.Text = 'Hold/Release';
            app.ROISizeEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ROISizeEditFieldLabel.Text = 'ROI Size';
            app.ROISizeEditField = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROISizeEditField_2 = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageDimensionsEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ImageDimensionsEditFieldLabel.Text = 'Image Dimensions';
            app.ImageDimensionsEditField = uieditfield(app.SideCameraPanel, 'numeric');
            app.ROISizeEditField_4 = uieditfield(app.SideCameraPanel, 'numeric');
            app.CrosshairWidthEditFieldLabel = uilabel(app.SideCameraPanel);
            app.CrosshairWidthEditFieldLabel.Text = 'Crosshair Width';
            app.CrosshairWidthEditField = uieditfield(app.SideCameraPanel, 'numeric');
            app.VideoDecimationEditFieldLabel = uilabel(app.SideCameraPanel);
            app.VideoDecimationEditFieldLabel.Text = 'Video Decimation';
            app.VideoDecimationEditField = uieditfield(app.SideCameraPanel, 'numeric');
            app.ImageWidthEditFieldLabel = uilabel(app.SideCameraPanel);
            app.ImageWidthEditFieldLabel.Text = 'Image Width';
            app.ImageWidthEditField = uieditfield(app.SideCameraPanel, 'numeric');
            app.SideCameraPanel_2 = uipanel(app.CameraControlTab);
            app.SideCameraPanel_2.Title = 'Side Camera';
            app.SideCameraLabel_2 = uilabel(app.SideCameraPanel_2);
            app.SideCameraLabel_2.Text = 'Side Camera';
            app.ROIHoldPositionpxEditFieldLabel_2 = uilabel(app.SideCameraPanel_2);
            app.ROIHoldPositionpxEditFieldLabel_2.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldSideRow_2 = uieditfield(app.SideCameraPanel_2, 'numeric');
            app.ROIHoldPositionpxEditFieldSideCol_2 = uieditfield(app.SideCameraPanel_2, 'numeric');
            app.HoldReleaseButtonSide_2 = uibutton(app.SideCameraPanel_2, 'push');
            app.HoldReleaseButtonSide_2.Text = 'Hold/Release';
            app.ROISizeEditField_5Label = uilabel(app.SideCameraPanel_2);
            app.ROISizeEditField_5Label.Text = 'ROI Size';
            app.ROISizeEditField_5 = uieditfield(app.SideCameraPanel_2, 'numeric');
            app.ROISizeEditField_6 = uieditfield(app.SideCameraPanel_2, 'numeric');
            app.ImageDimensionsEditField_2Label = uilabel(app.SideCameraPanel_2);
            app.ImageDimensionsEditField_2Label.Text = 'Image Dimensions';
            app.ImageDimensionsEditField_2 = uieditfield(app.SideCameraPanel_2, 'numeric');
            app.ROISizeEditField_7 = uieditfield(app.SideCameraPanel_2, 'numeric');
            app.CrosshairWidthEditField_2Label = uilabel(app.SideCameraPanel_2);
            app.CrosshairWidthEditField_2Label.Text = 'Crosshair Width';
            app.CrosshairWidthEditField_2 = uieditfield(app.SideCameraPanel_2, 'numeric');
            app.VideoDecimationEditField_2Label = uilabel(app.SideCameraPanel_2);
            app.VideoDecimationEditField_2Label.Text = 'Video Decimation';
            app.VideoDecimationEditField_2 = uieditfield(app.SideCameraPanel_2, 'numeric');
            app.ImageWidthEditField_2Label = uilabel(app.SideCameraPanel_2);
            app.ImageWidthEditField_2Label.Text = 'Image Width';
            app.ImageWidthEditField_2 = uieditfield(app.SideCameraPanel_2, 'numeric');
            app.SideCameraPanel_3 = uipanel(app.CameraControlTab);
            app.SideCameraPanel_3.Title = 'Side Camera';
            app.SideCameraLabel_3 = uilabel(app.SideCameraPanel_3);
            app.SideCameraLabel_3.Text = 'Side Camera';
            app.ROIHoldPositionpxEditFieldLabel_3 = uilabel(app.SideCameraPanel_3);
            app.ROIHoldPositionpxEditFieldLabel_3.Text = 'ROI Hold Position (px)';
            app.ROIHoldPositionpxEditFieldSideRow_3 = uieditfield(app.SideCameraPanel_3, 'numeric');
            app.ROIHoldPositionpxEditFieldSideCol_3 = uieditfield(app.SideCameraPanel_3, 'numeric');
            app.HoldReleaseButtonSide_3 = uibutton(app.SideCameraPanel_3, 'push');
            app.HoldReleaseButtonSide_3.Text = 'Hold/Release';
            app.ROISizeEditField_8Label = uilabel(app.SideCameraPanel_3);
            app.ROISizeEditField_8Label.Text = 'ROI Size';
            app.ROISizeEditField_8 = uieditfield(app.SideCameraPanel_3, 'numeric');
            app.ROISizeEditField_9 = uieditfield(app.SideCameraPanel_3, 'numeric');
            app.ImageDimensionsEditField_3Label = uilabel(app.SideCameraPanel_3);
            app.ImageDimensionsEditField_3Label.Text = 'Image Dimensions';
            app.ImageDimensionsEditField_3 = uieditfield(app.SideCameraPanel_3, 'numeric');
            app.ROISizeEditField_10 = uieditfield(app.SideCameraPanel_3, 'numeric');
            app.CrosshairWidthEditField_3Label = uilabel(app.SideCameraPanel_3);
            app.CrosshairWidthEditField_3Label.Text = 'Crosshair Width';
            app.CrosshairWidthEditField_3 = uieditfield(app.SideCameraPanel_3, 'numeric');
            app.VideoDecimationEditField_3Label = uilabel(app.SideCameraPanel_3);
            app.VideoDecimationEditField_3Label.Text = 'Video Decimation';
            app.VideoDecimationEditField_3 = uieditfield(app.SideCameraPanel_3, 'numeric');
            app.ImageWidthEditField_3Label = uilabel(app.SideCameraPanel_3);
            app.ImageWidthEditField_3Label.Text = 'Image Width';
            app.ImageWidthEditField_3 = uieditfield(app.SideCameraPanel_3, 'numeric');
            app.CameraSettingsTab = uitab(app.TabGroup);
            app.CameraSettingsTab.Title = 'Camera Settings';
            app.DotPropertiesPanel = uipanel(app.CameraSettingsTab);
            app.DotPropertiesPanel.Title = 'Dot Properties';
            app.SideDotSeparationDistanceEditField_2Label = uilabel(app.DotPropertiesPanel);
            app.SideDotSeparationDistanceEditField_2Label.Text = 'Side Dot Separation Distance';
            app.SideDotSeparationDistanceEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldLabel_2 = uilabel(app.DotPropertiesPanel);
            app.SideDotPositionVectorEditFieldLabel_2.Text = 'Side Dot Position Vector';
            app.SideDotPositionVectorEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotSeparationDistanceEditField_2Label = uilabel(app.DotPropertiesPanel);
            app.BottomADotSeparationDistanceEditField_2Label.Text = 'Bottom A Dot Separation Distance';
            app.BottomADotSeparationDistanceEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldLabel_2 = uilabel(app.DotPropertiesPanel);
            app.BottomADotPositionVectorEditFieldLabel_2.Text = 'Bottom A Dot Position Vector';
            app.BottomADotPositionVectorEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotSeparationDistanceEditField_2Label = uilabel(app.DotPropertiesPanel);
            app.BottomBDotSeparationDistanceEditField_2Label.Text = 'Bottom B Dot Separation Distance';
            app.BottomBDotSeparationDistanceEditField = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionValuieEditFieldLabel_2 = uilabel(app.DotPropertiesPanel);
            app.BottomBDotPositionValuieEditFieldLabel_2.Text = 'Bottom B Dot Position Valuie';
            app.BottomBDotPositionValuieEditFieldX = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.SideDotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomADotPositionVectorEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionValuieEditFieldY = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.BottomBDotPositionValuieEditFieldZ = uieditfield(app.DotPropertiesPanel, 'numeric');
            app.CameraSpecificationsPanel = uipanel(app.CameraSettingsTab);
            app.CameraSpecificationsPanel.Title = 'Camera Specifications';
            app.CameraFOVEditFieldLabel_2 = uilabel(app.CameraSpecificationsPanel);
            app.CameraFOVEditFieldLabel_2.Text = 'Camera FOV';
            app.CameraFOVEditFieldHor = uieditfield(app.CameraSpecificationsPanel, 'numeric');
            app.CameraFOVEditFieldVer = uieditfield(app.CameraSpecificationsPanel, 'numeric');
            app.ImageErrorSettingsPanel = uipanel(app.CameraSettingsTab);
            app.ImageErrorSettingsPanel.Title = 'Image Error Settings';
            app.ThresholdEditField_2Label = uilabel(app.ImageErrorSettingsPanel);
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ImageErrorSettingsPanel, 'numeric');
            app.DebounceTimeEditField_2Label = uilabel(app.ImageErrorSettingsPanel);
            app.DebounceTimeEditField_2Label.Text = 'Debounce Time';
            app.DebounceTimeEditField = uieditfield(app.ImageErrorSettingsPanel, 'numeric');
            app.CameraOrientationPanel = uipanel(app.CameraSettingsTab);
            app.CameraOrientationPanel.Title = 'Camera Orientation';
            app.SideCameraLabel2 = uilabel(app.CameraOrientationPanel);
            app.SideCameraLabel2.Text = 'Side Camera';
            app.EulerAngleEditFieldLabel_2 = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditFieldLabel_2.Text = 'Euler Angle';
            app.EulerAngleEditFieldSideRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.BottomCameraLabel = uilabel(app.CameraOrientationPanel);
            app.BottomCameraLabel.Text = 'Bottom Camera';
            app.EulerAngleEditField_2Label_2 = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditField_2Label_2.Text = 'Euler Angle';
            app.EulerAngleEditFieldBottomRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomPitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldBottomYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_8Label_2 = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_8Label_2.Text = 'Position';
            app.PositionEditFieldBottomX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldBottomZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.SlantCameraLabel2 = uilabel(app.CameraOrientationPanel);
            app.SlantCameraLabel2.Text = 'Slant Camera';
            app.EulerAngleEditField_3Label_2 = uilabel(app.CameraOrientationPanel);
            app.EulerAngleEditField_3Label_2.Text = 'Euler Angle';
            app.EulerAngleEditFieldSlantRoll = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_11Label_2 = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_11Label_2.Text = 'Position';
            app.PositionEditFieldSlantX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantPitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSlantYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSlantZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditField_16Label_2 = uilabel(app.CameraOrientationPanel);
            app.PositionEditField_16Label_2.Text = 'Position';
            app.PositionEditFieldSideX = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSidePitch = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.EulerAngleEditFieldSideYaw = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideY = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.PositionEditFieldSideZ = uieditfield(app.CameraOrientationPanel, 'numeric');
            app.CommunicationTab = uitab(app.TabGroup);
            app.CommunicationTab.Title = 'Communication';
            app.UDPPTPConfigurationPanel = uipanel(app.CommunicationTab);
            app.UDPPTPConfigurationPanel.Title = 'UDP/PTP Configuration';
            app.PTPOffsetEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.PTPOffsetEditFieldLabel.Text = 'PTP Offset';
            app.PTPOffsetEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.SyncIntervalEditFieldLabel = uilabel(app.UDPPTPConfigurationPanel);
            app.SyncIntervalEditFieldLabel.Text = 'Sync Interval';
            app.SyncIntervalEditField = uieditfield(app.UDPPTPConfigurationPanel, 'numeric');
            app.Machine2Panel = uipanel(app.CommunicationTab);
            app.Machine2Panel.Title = 'Machine 2';
            app.IPAddressEditFieldLabel = uilabel(app.Machine2Panel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditFieldMachine2 = uieditfield(app.Machine2Panel, 'numeric');
            app.PortNumberEditFieldLabel = uilabel(app.Machine2Panel);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditFieldMachine2 = uieditfield(app.Machine2Panel, 'numeric');
            app.MinDelayEditFieldLabel = uilabel(app.Machine2Panel);
            app.MinDelayEditFieldLabel.Text = 'Min Delay';
            app.MinDelayEditFieldMachine2 = uieditfield(app.Machine2Panel, 'numeric');
            app.Machine3Panel = uipanel(app.CommunicationTab);
            app.Machine3Panel.Title = 'Machine 3';
            app.IPAddressEditField_2Label = uilabel(app.Machine3Panel);
            app.IPAddressEditField_2Label.Text = 'IP Address';
            app.IPAddressEditFieldMachine3 = uieditfield(app.Machine3Panel, 'numeric');
            app.PortNumberEditField_2Label = uilabel(app.Machine3Panel);
            app.PortNumberEditField_2Label.Text = 'Port Number';
            app.PortNumberEditFieldMachine3 = uieditfield(app.Machine3Panel, 'numeric');
            app.MinDelayEditField_2Label = uilabel(app.Machine3Panel);
            app.MinDelayEditField_2Label.Text = 'Min Delay';
            app.MinDelayEditFieldMachine3 = uieditfield(app.Machine3Panel, 'numeric');
            app.Machine4Panel = uipanel(app.CommunicationTab);
            app.Machine4Panel.Title = 'Machine 4';
            app.IPAddressEditField_3Label = uilabel(app.Machine4Panel);
            app.IPAddressEditField_3Label.Text = 'IP Address';
            app.IPAddressEditFieldMachine4 = uieditfield(app.Machine4Panel, 'numeric');
            app.PortNumberEditField_3Label = uilabel(app.Machine4Panel);
            app.PortNumberEditField_3Label.Text = 'Port Number';
            app.PortNumberEditFieldMachine4 = uieditfield(app.Machine4Panel, 'numeric');
            app.MinDelayEditField_3Label = uilabel(app.Machine4Panel);
            app.MinDelayEditField_3Label.Text = 'Min Delay';
            app.MinDelayEditFieldMachine4 = uieditfield(app.Machine4Panel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end